classdef sm_forklift_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForklkftStabilityTest           matlab.ui.Figure
        NormalForcesCodeButton          matlab.ui.control.Button
        NormalForcesButton              matlab.ui.control.Button
        BodyPositionCodeButton          matlab.ui.control.Button
        BodyPositionButton              matlab.ui.control.Button
        WheelSpeedCodeButton            matlab.ui.control.Button
        WheelSpeedsButton               matlab.ui.control.Button
        PlotResultsLabel                matlab.ui.control.Label
        PlotModelInputsButton           matlab.ui.control.Button
        ForkliftStabilityTestDefinitionLabel  matlab.ui.control.Label
        SweepCheckBox                   matlab.ui.control.CheckBox
        RunTestButton                   matlab.ui.control.Button
        CreateModelInputsButton         matlab.ui.control.Button
        LoadkgEditField                 matlab.ui.control.EditField
        LoadkgEditFieldLabel            matlab.ui.control.Label
        SideShiftmmEditField            matlab.ui.control.EditField
        SideShiftmmEditFieldLabel       matlab.ui.control.Label
        SteerAngledegEditField          matlab.ui.control.EditField
        SteerAngledegEditFieldLabel     matlab.ui.control.Label
        MastTiltAngledegEditField       matlab.ui.control.EditField
        MastTiltAngledegEditFieldLabel  matlab.ui.control.Label
        MastLiftPositionmmEditField     matlab.ui.control.EditField
        MastLiftPositionmmEditFieldLabel  matlab.ui.control.Label
        TargetVelocitymsEditField       matlab.ui.control.EditField
        TargetVelocitymsEditFieldLabel  matlab.ui.control.Label
        TestTypeDropDown                matlab.ui.control.DropDown
        TestTypeDropDownLabel           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = string2double(app,str)
            results = str2double(strsplit(strjoin(regexp(str,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'),' ')));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateModelInputsButton
        function CreateModelInputsButtonPushed(app, event)
            velStr   = app.TargetVelocitymsEditField.Value;
            testCond.vel      = string2double(app,velStr);
            liftStr  = app.MastLiftPositionmmEditField.Value;
            testCond.lift     = string2double(app,liftStr);
            tiltStr  = app.MastTiltAngledegEditField.Value;
            testCond.tilt     = string2double(app,tiltStr);
            shiftStr = app.SideShiftmmEditField.Value;
            testCond.shift    = string2double(app,shiftStr);
            loadStr  = app.LoadkgEditField.Value;
            testCond.load     = string2double(app,loadStr);
            steerStr = app.SteerAngledegEditField.Value;
            testCond.steer    = string2double(app,steerStr);            
            testCond.type  = app.TestTypeDropDown.Value;
            if(app.SweepCheckBox.Value)
                testCond.type = [testCond.type 'Sweep'];
            end
            assignin('base',"testCond",testCond);
            evalin('base','testCond')

            if(~app.SweepCheckBox.Value)
                evalstr = 'sm_forklift_create_test(testCond);';
                disp(evalstr);
                evalin('base',evalstr);
            else
                disp('Creating Simulation Input Objects...')
                evalin('base','[simIn,StabilitySweepInitCond] = sm_forklift_run_sweep(testCond,false);');
                numSims = evalin('base','length(simIn);');
                disp(['... done (' num2str(numSims) ' simulations).']);
            end

        end

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
            CreateModelInputsButtonPushed(app,event)
            if(~app.SweepCheckBox.Value)
                evalstr = 'sm_forklift_create_test(testCond);';
                disp(evalstr);
                evalin('base',evalstr);
                mdl = "sm_forklift";
                open_system(mdl);
                out=sim(mdl);
                assignin('base','out',out);
            else
                evalin('base','sm_forklift_run_sweep(testCond,true);');
            end
        end

        % Value changed function: SweepCheckBox
        function SweepCheckBoxValueChanged(app, event)
            value = app.SweepCheckBox.Value;
            if(value)
                app.PlotModelInputsButton.Enable = "off";
            else
                app.PlotModelInputsButton.Enable = "on";
            end
        end

        % Button pushed function: PlotModelInputsButton
        function PlotModelInputsButtonPushed(app, event)
            CreateModelInputsButtonPushed(app,event)
            evalStr = 'sm_forklift_plot3activeTestCmds(activeTestCmds)';
            disp(evalStr)
            evalin('base',evalStr)
        end

        % Button pushed function: WheelSpeedsButton
        function WheelSpeedsButtonPushed(app, event)
            evalStr = 'sm_forklift_plot1whlspd(out.simlog_sm_forklift,ForkliftParams.groundContact.frontTireRad,ForkliftParams.groundContact.rearTireRad);';
            disp(evalStr)
            evalin('base',evalStr)            
        end

        % Button pushed function: BodyPositionButton
        function BodyPositionButtonPushed(app, event)
            evalStr = 'sm_forklift_plot2bodypos(out.simlog_sm_forklift);';
            disp(evalStr)
            evalin('base',evalStr)             
        end

        % Button pushed function: WheelSpeedCodeButton
        function WheelSpeedCodeButtonPushed(app, event)
            edit sm_forklift_plot1whlspd.m
        end

        % Button pushed function: BodyPositionCodeButton
        function BodyPositionCodeButtonPushed(app, event)
            edit sm_forklift_plot2bodypos.m
        end

        % Button pushed function: NormalForcesButton
        function NormalForcesButtonPushed(app, event)
            evalStr = 'sm_forklift_plot4whlfn(out.logsout_sm_forklift);';
            disp(evalStr)
            evalin('base',evalStr)            
        end

        % Button pushed function: NormalForcesCodeButton
        function NormalForcesCodeButtonPushed(app, event)
             edit sm_forklift_plot4whlfn.m
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForklkftStabilityTest = uifigure('Visible', 'off');
            app.ForklkftStabilityTest.Name = 'Forklift Stability Test App';
            app.TestTypeDropDownLabel = uilabel(app.ForklkftStabilityTest);
            app.TestTypeDropDownLabel.Text = 'Test Type';
            app.TestTypeDropDown = uidropdown(app.ForklkftStabilityTest);
            app.TestTypeDropDown.Items = {'Static Load', 'Mast Lift', 'Step Steer', 'Constant Radius', 'Mast Lower Brake', 'Tilt Lift Shift'};
            app.TestTypeDropDown.ItemsData = {'StaticLoad', 'MastLift', 'StepSteer', 'ConstantRadius', 'MastLowerBrake', 'TiltLiftShift'};
            app.TestTypeDropDown.Value = 'StaticLoad';
            app.TargetVelocitymsEditFieldLabel = uilabel(app.ForklkftStabilityTest);
            app.TargetVelocitymsEditFieldLabel.Text = 'Target Velocity (m/s)';
            app.TargetVelocitymsEditField = uieditfield(app.ForklkftStabilityTest, 'text');
            app.TargetVelocitymsEditField.Value = '2';
            app.MastLiftPositionmmEditFieldLabel = uilabel(app.ForklkftStabilityTest);
            app.MastLiftPositionmmEditFieldLabel.Text = 'Mast Lift Position (mm)';
            app.MastLiftPositionmmEditField = uieditfield(app.ForklkftStabilityTest, 'text');
            app.MastLiftPositionmmEditField.Value = '3200';
            app.MastTiltAngledegEditFieldLabel = uilabel(app.ForklkftStabilityTest);
            app.MastTiltAngledegEditFieldLabel.Text = 'Mast Tilt Angle (deg)';
            app.MastTiltAngledegEditField = uieditfield(app.ForklkftStabilityTest, 'text');
            app.MastTiltAngledegEditField.Value = '5';
            app.SteerAngledegEditFieldLabel = uilabel(app.ForklkftStabilityTest);
            app.SteerAngledegEditFieldLabel.Text = 'Steer Angle (deg)';
            app.SteerAngledegEditField = uieditfield(app.ForklkftStabilityTest, 'text');
            app.SteerAngledegEditField.Value = '35';
            app.SideShiftmmEditFieldLabel = uilabel(app.ForklkftStabilityTest);
            app.SideShiftmmEditFieldLabel.Text = 'Side Shift (mm)';
            app.SideShiftmmEditField = uieditfield(app.ForklkftStabilityTest, 'text');
            app.SideShiftmmEditField.Value = '-152.4';
            app.LoadkgEditFieldLabel = uilabel(app.ForklkftStabilityTest);
            app.LoadkgEditFieldLabel.Text = 'Load (kg)';
            app.LoadkgEditField = uieditfield(app.ForklkftStabilityTest, 'text');
            app.LoadkgEditField.Value = '1200';
            app.CreateModelInputsButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.CreateModelInputsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateModelInputsButtonPushed, true);
            app.CreateModelInputsButton.Text = 'Create Model Inputs';
            app.RunTestButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Text = 'Run Test';
            app.SweepCheckBox = uicheckbox(app.ForklkftStabilityTest);
            app.SweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @SweepCheckBoxValueChanged, true);
            app.SweepCheckBox.Text = 'Sweep';
            app.ForkliftStabilityTestDefinitionLabel = uilabel(app.ForklkftStabilityTest);
            app.ForkliftStabilityTestDefinitionLabel.Text = 'Forklift Stability Test Definition';
            app.PlotModelInputsButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.PlotModelInputsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModelInputsButtonPushed, true);
            app.PlotModelInputsButton.Text = 'Plot Model Inputs';
            app.PlotResultsLabel = uilabel(app.ForklkftStabilityTest);
            app.PlotResultsLabel.Text = 'Plot Results';
            app.WheelSpeedsButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.WheelSpeedsButton.ButtonPushedFcn = createCallbackFcn(app, @WheelSpeedsButtonPushed, true);
            app.WheelSpeedsButton.Text = 'Wheel Speeds';
            app.WheelSpeedCodeButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.WheelSpeedCodeButton.ButtonPushedFcn = createCallbackFcn(app, @WheelSpeedCodeButtonPushed, true);
            app.WheelSpeedCodeButton.Text = 'Code';
            app.BodyPositionButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.BodyPositionButton.ButtonPushedFcn = createCallbackFcn(app, @BodyPositionButtonPushed, true);
            app.BodyPositionButton.Text = 'Body Position';
            app.BodyPositionCodeButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.BodyPositionCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BodyPositionCodeButtonPushed, true);
            app.BodyPositionCodeButton.Text = 'Code';
            app.NormalForcesButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.NormalForcesButton.ButtonPushedFcn = createCallbackFcn(app, @NormalForcesButtonPushed, true);
            app.NormalForcesButton.Text = 'Normal Forces';
            app.NormalForcesCodeButton = uibutton(app.ForklkftStabilityTest, 'push');
            app.NormalForcesCodeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalForcesCodeButtonPushed, true);
            app.NormalForcesCodeButton.Text = 'Code';
            % Show the figure after all components are created
            app.ForklkftStabilityTest.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sm_forklift_test_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ForklkftStabilityTest)
            else

                % Focus the running singleton app
                figure(runningApp.ForklkftStabilityTest)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForklkftStabilityTest)
        end
    end
end